classdef LPRAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        KedahLabel                matlab.ui.control.Label
        StateIdentificationLabel  matlab.ui.control.Label
        ResultLabel               matlab.ui.control.Label
        LoadCustomImageButton     matlab.ui.control.Button
        ResultImageAxes           matlab.ui.control.UIAxes
        ImageAxes                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updateImage(app,imagefile)
            
            try
                im = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure,ME.message,"Image Error");
                return;
            end           
            
            %show current upload image
            imagesc(app.ImageAxes,im);

            originalImage = im;
            
            % Convert to grayscale
            grayImage = rgb2gray(originalImage);
            
            % Apply preprocessing techniques
            % 1. Contrast enhancement
            enhancedImage = adapthisteq(grayImage);
            
            % 2. Noise reduction
            denoisedImage = medfilt2(enhancedImage);
            
            % 3. Edge detection (Sobel operator)
            edgeImage = edge(denoisedImage, 'sobel');
            
            % 4. Morphological operations for plate region detection
            % Dilation to connect potential plate regions
            dilatedEdges = imdilate(edgeImage, strel('rectangle', [3 15]));
            
            % Fill holes
            filledRegions = imfill(dilatedEdges, 'holes');
            
            % 5. Candidate region selection
            % Use region properties to filter potential plate regions
            regionProps = regionprops(filledRegions, ...
                'BoundingBox', 'Area', 'Extent');
            
            % Criteria for Malaysian license plate:
            % - Typical aspect ratio: ~3:1 to 4:1
            % - Minimum and maximum area based on image size
            plateRegions = [];
            
            % Create a copy of the original image to draw rectangles
            rectangleImage = originalImage;
            
            for i = 1:length(regionProps)
                % Calculate aspect ratio manually
                bbox = regionProps(i).BoundingBox;
                aspectRatio = bbox(3) / bbox(4);
                
                % Area check (proportional to image size)
                area = regionProps(i).Area;
                imageArea = size(originalImage, 1) * size(originalImage, 2);
                
                % Filter conditions
                if (aspectRatio >= 3 && aspectRatio <= 4.5) && ...
                   (area > 0.001 * imageArea && area < 0.1 * imageArea)
                    plateRegions = [plateRegions, i];
                    
                    % Draw rectangle for potential plate regions
                    rectangleImage = insertShape(rectangleImage, 'Rectangle', ...
                        regionProps(i).BoundingBox, 'Color', 'green', 'LineWidth', 2);
                    
                    % Draw rectangle for all detected regions
                    rectangleImage = insertShape(rectangleImage, 'Rectangle', ...
                        bbox, 'Color', 'red', 'LineWidth', 1);
                else
                    % Draw rectangle for non-plate regions in blue
                    rectangleImage = insertShape(rectangleImage, 'Rectangle', ...
                        bbox, 'Color', 'blue', 'LineWidth', 1);
                end
            end
            
            % 6. Plate extraction and OCR
            if ~isempty(plateRegions)
                % Select the most likely plate region
                bestPlateIndex = plateRegions(1);
                plateBoundingBox = round(regionProps(bestPlateIndex).BoundingBox);
                
                % Extract plate region
                plateImage = imcrop(grayImage, plateBoundingBox);
                
                % Binarization
                binaryPlate = imbinarize(plateImage, 'adaptive');
                
                % 7. Character Segmentation
                % Use connected component analysis
                connectedComponents = bwlabel(binaryPlate);
                charProps = regionprops(connectedComponents, ...
                    'BoundingBox', 'Area', 'Extent');
                
                % Filter and sort characters
                validChars = [];
                for j = 1:length(charProps)
                    % Character filtering criteria
                    charBBox = charProps(j).BoundingBox;
                    charAspectRatio = charBBox(3) / charBBox(4);
                    
                    if (charAspectRatio > 0.2 && charAspectRatio < 1) && ...
                       (charProps(j).Area > 50)
                        validChars = [validChars, j];
                    end
                end
                
                % 8. Visualization
                %show result image
                imagesc(app.ResultImageAxes,binaryPlate);

                %figure('Position', [100, 100, 1200, 800]);
                
                % Subplots for different stages of processing
                %subplot(2,2,1), imshow(originalImage), title('Original Image');
                %subplot(2,2,2), imshow(edgeImage), title('Edge Detection');
                %subplot(2,2,3), imshow(binaryPlate), title('Plate Region');
                %subplot(2,2,4), imshow(rectangleImage), title('Detected Regions');
                
                % Add text explanation to the rectangle visualization
                %subplot(2,2,4);
                %text(10, size(rectangleImage,1) + 20, ...
                    %'Rectangle Colors:', 'FontWeight', 'bold');
                %text(10, size(rectangleImage,1) + 40, ...
                    %'Green: Potential Plate Regions', 'Color', 'green');
                %text(10, size(rectangleImage,1) + 60, ...
                    %'Red: Plate Region Boundaries', 'Color', 'red');
                %text(10, size(rectangleImage,1) + 80, ...
                    %'Blue: Non-Plate Regions', 'Color', 'blue');
                
                % Optional: OCR (Note: Requires Computer Vision Toolbox)
                try
                    ocrResults = ocr(binaryPlate);
      
                    uialert(app.UIFigure,ocrResults.Text,"OCR Result");
                   
                catch
                   uialert(app.UIFigure,"OCR cannot be perform.","OCR Error");
                end
            else
                uialert(app.UIFigure,"No license plate detected","Image Warning");
            end


            % Create histograms based on number of color channels
            %switch size(im,3)
                %case 1
                    % Display the grayscale image
                    %imagesc(app.ImageAxes,im);
                    
                    % Plot all histograms with the same data for grayscale
                    %histr = histogram(app.RedAxes,im,"FaceColor",[1 0 0],"EdgeColor","none");
                    %histg = histogram(app.GreenAxes,im,"FaceColor",[0 1 0],"EdgeColor","none");
                    %histb = histogram(app.BlueAxes,im,"FaceColor",[0 0 1],"EdgeColor","none");
                    
                %case 3
                    % Display the truecolor image
                    %imagesc(app.ImageAxes,im);
                    
                    % Plot the histograms
                    %histr = histogram(app.RedAxes,im(:,:,1),"FaceColor",[1 0 0],"EdgeColor","none");
                    %histg = histogram(app.GreenAxes,im(:,:,2),"FaceColor",[0 1 0],"EdgeColor","none");
                    %histb = histogram(app.BlueAxes,im(:,:,3),"FaceColor",[0 0 1],"EdgeColor","none");
                    
                %otherwise
                    % Error when image is not grayscale or truecolor
                    %uialert(app.UIFigure,"Image must be grayscale or truecolor.","Image Error");
                    %return;
            %end
                % Get largest bin count
                %maxr = max(histr.BinCounts);
                %maxg = max(histg.BinCounts);
                %maxb = max(histb.BinCounts);
                %maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                %app.RedAxes.YLim = [0 maxcount];
                %app.RedAxes.YTick = round([0 maxcount/2 maxcount],2,"significant");
                %app.GreenAxes.YLim = [0 maxcount];
                %app.GreenAxes.YTick = round([0 maxcount/2 maxcount],2,"significant");
                %app.BlueAxes.YLim = [0 maxcount];
                %app.BlueAxes.YTick = round([0 maxcount/2 maxcount],2,"significant");
         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = "off";
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes,"image");

            app.ResultImageAxes.Visible = "off";
            app.ResultImageAxes.Colormap = gray(256);
            axis(app.ResultImageAxes,"image");
            
            % Update the image and histograms
            %updateImage(app,"peppers.png");
        end

        % Button pushed function: LoadCustomImageButton
        function LoadCustomImageButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [file, path] = uigetfile(filterspec);

            if isequal(file, 0)
                return;
            end
            
            % Make sure user didn't cancel uigetfile dialog 
            if (ischar(path))
               fname = [path file];
               updateImage(app,fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.ResultImageAxes = uiaxes(app.UIFigure);
            app.ResultImageAxes.XTick = [];
            app.ResultImageAxes.XTickLabel = {'[ ]'};
            app.ResultImageAxes.YTick = [];
            app.LoadCustomImageButton = uibutton(app.UIFigure, 'push');
            app.LoadCustomImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCustomImageButtonPushed, true);
            app.LoadCustomImageButton.Text = 'Load Custom Image';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.StateIdentificationLabel = uilabel(app.UIFigure);
            app.StateIdentificationLabel.Text = 'State Identification';
            app.KedahLabel = uilabel(app.UIFigure);
            app.KedahLabel.Text = 'Kedah';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LPRAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end